classdef uno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        updatecardButton    matlab.ui.control.Button
        cardEditField       matlab.ui.control.EditField
        cardEditFieldLabel  matlab.ui.control.Label
        YourHandPanel       matlab.ui.container.Panel
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        numplayers = 4;
        playerturn = 1;

    end
    
    properties (Access = public)
        deck % Description
    end
    
    methods (Access = private)
        function [] = updateturn(app)
%When calling this function provide both the current turn number from app
%as well as the totalplayer number also from app
if app.playerturn < app.numplayers
    app.playerturn = app.playerturn + 1;
else
    app.playerturn = 1;
end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
            app.deck = Deck();
            size(app.deck.deckArray)


        end

        % Button pushed function: updatecardButton
        function updatecardButtonPushed(app, event)
            pickedCard = app.deck.pullCard();


            app.Image.ImageSource = pickedCard.imageSource;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourHandPanel = uipanel(app.UIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image = uiimage(app.YourHandPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'blue0.png');
            app.cardEditFieldLabel = uilabel(app.UIFigure);
            app.cardEditFieldLabel.Text = 'card';
            app.cardEditField = uieditfield(app.UIFigure, 'text');
            app.updatecardButton = uibutton(app.UIFigure, 'push');
            app.updatecardButton.ButtonPushedFcn = createCallbackFcn(app, @updatecardButtonPushed, true);
            app.updatecardButton.Text = 'update card';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end